classdef AutoPageUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Logo                    matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        AutoLabel               matlab.ui.control.Label
        Image2                  matlab.ui.control.Image
        ReturnButton            matlab.ui.control.Button
        FinsihButton            matlab.ui.control.Button
        ChannelEditFieldLabel   matlab.ui.control.Label
        ChannelEditField        matlab.ui.control.EditField
        ExposureEditFieldLabel  matlab.ui.control.Label
        ExposureEditField       matlab.ui.control.EditField
        ModeDropDownLabel       matlab.ui.control.Label
        ModeDropDown            matlab.ui.control.DropDown
        XnumberEditFieldLabel   matlab.ui.control.Label
        XnumberEditField        matlab.ui.control.EditField
        YnumberEditFieldLabel   matlab.ui.control.Label
        YnumberEditField        matlab.ui.control.EditField
        XoriginEditFieldLabel   matlab.ui.control.Label
        XoriginEditField        matlab.ui.control.EditField
        YoriginEditFieldLabel   matlab.ui.control.Label
        YoriginEditField        matlab.ui.control.EditField
        ScaleEditFieldLabel     matlab.ui.control.Label
        ScaleEditField          matlab.ui.control.EditField
        WallPathEditFieldLabel  matlab.ui.control.Label
        WallPathEditField       matlab.ui.control.EditField
        If_NLOSCheckBox         matlab.ui.control.CheckBox
        MeasPathEditFieldLabel  matlab.ui.control.Label
        MeasPathEditField       matlab.ui.control.EditField
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        page % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            app.page.pres = app.page.Page_auto;
            app.page.next = app.page.Page_home;
            app.page.Switch_Page();
        end

        % Button pushed function: FinsihButton
        function FinsihButtonPushed(app, event)
            root = "C:/Users/xxy/Desktop/Inphormatool/resources";
            galvo_path = strcat(root, "/hardware/galvo");
            input_path = strcat(root, "/input");
            output_path = strcat(root, "/output");
            channel = app.ChannelEditField.Value;
            exposure = round(str2double(app.ExposureEditField.Value));
            num_pix_0 = round(str2double(app.XnumberEditField.Value));
            num_pix_1 = round(str2double(app.YnumberEditField.Value));
            voltage_x_origin = str2double(app.XoriginEditField.Value);
            voltage_y_origin = str2double(app.YoriginEditField.Value);
            scale = str2double(app.ScaleEditField.Value);
            if_nlos = app.If_NLOSCheckBox.Value;
            mode = app.ModeDropDown.Value;
            wall_path = app.WallPathEditField.Value;
            meas_path = app.MeasPathEditField.Value;
            save(strcat(meas_path, "/meas_setting.mat"), 'channel','exposure','num_pix_0','num_pix_1','voltage_x_origin','voltage_y_origin','scale','mode','wall_path',"if_nlos");
            meas_setting_input(root, meas_path, galvo_path, wall_path, input_path);
            while(1)
                flag = fileread(strcat(input_path, "/running_flag.txt"));
                if flag == '0'
                    break
                end
                i = 0;
                while(i<100)
                    i = i+1;
                end
            end
            load(strcat(output_path, "/data.mat"), 'data');
            save(strcat(meas_path, "/meas_output.mat"), 'data');
            app.page.pres = app.page.Page_auto;
            app.page.next = app.page.Page_home;
            app.page.Switch_Page();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Logo = uiimage(app.UIFigure);
            app.Logo.ImageSource = 'logo.png';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Background.png';
            app.AutoLabel = uilabel(app.UIFigure);
            app.AutoLabel.Text = 'Auto';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Background2.png';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.FinsihButton = uibutton(app.UIFigure, 'push');
            app.FinsihButton.ButtonPushedFcn = createCallbackFcn(app, @FinsihButtonPushed, true);
            app.FinsihButton.Text = 'Finsih';
            app.ChannelEditFieldLabel = uilabel(app.UIFigure);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.UIFigure, 'text');
            app.ExposureEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureEditFieldLabel.Text = 'Exposure';
            app.ExposureEditField = uieditfield(app.UIFigure, 'text');
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'u', 'mc'};
            app.ModeDropDown.Value = 'u';
            app.XnumberEditFieldLabel = uilabel(app.UIFigure);
            app.XnumberEditFieldLabel.Text = 'X number';
            app.XnumberEditField = uieditfield(app.UIFigure, 'text');
            app.YnumberEditFieldLabel = uilabel(app.UIFigure);
            app.YnumberEditFieldLabel.Text = 'Y number';
            app.YnumberEditField = uieditfield(app.UIFigure, 'text');
            app.XoriginEditFieldLabel = uilabel(app.UIFigure);
            app.XoriginEditFieldLabel.Text = 'X origin';
            app.XoriginEditField = uieditfield(app.UIFigure, 'text');
            app.YoriginEditFieldLabel = uilabel(app.UIFigure);
            app.YoriginEditFieldLabel.Text = 'Y origin';
            app.YoriginEditField = uieditfield(app.UIFigure, 'text');
            app.ScaleEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.UIFigure, 'text');
            app.WallPathEditFieldLabel = uilabel(app.UIFigure);
            app.WallPathEditFieldLabel.Text = 'Wall Path';
            app.WallPathEditField = uieditfield(app.UIFigure, 'text');
            app.If_NLOSCheckBox = uicheckbox(app.UIFigure);
            app.If_NLOSCheckBox.Text = 'If_NLOS';
            app.MeasPathEditFieldLabel = uilabel(app.UIFigure);
            app.MeasPathEditFieldLabel.Text = 'Meas Path';
            app.MeasPathEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AutoPageUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end