classdef CalibrationPageUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Logo                    matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        CalibrationLabel        matlab.ui.control.Label
        Image2                  matlab.ui.control.Image
        GalvoButton             matlab.ui.control.Button
        JitterButton            matlab.ui.control.Button
        RelayWallButton         matlab.ui.control.Button
        ReturnButton            matlab.ui.control.Button
        FinsihButton            matlab.ui.control.Button
        Lamp                    matlab.ui.control.Lamp
        Lamp_2                  matlab.ui.control.Lamp
        Lamp_3                  matlab.ui.control.Lamp
        ChangeButton            matlab.ui.control.Button
        ChangeButton_2          matlab.ui.control.Button
        ChangeButton_3          matlab.ui.control.Button
        WallPathEditFieldLabel  matlab.ui.control.Label
        WallPathEditField       matlab.ui.control.EditField
        LossLabel               matlab.ui.control.Label
        loss                    matlab.ui.control.Label
        FWHMLabel               matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        RootEditFieldLabel      matlab.ui.control.Label
        RootEditField           matlab.ui.control.EditField
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        page % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            app.page.pres = app.page.Page_cali;
            app.page.next = app.page.Page_home;
            app.page.Switch_Page();
        end

        % Button pushed function: FinsihButton
        function FinsihButtonPushed(app, event)
            app.page.pres = app.page.Page_cali;
            app.page.next = app.page.Page_home;
            app.page.Switch_Page();
        end

        % Button pushed function: GalvoButton
        function GalvoButtonPushed(app, event)
            root = app.RootEditField.Value;
            num_galvo_0 = 8;
            num_galvo_1 = 8;
            voltage_x_min = -9;
            voltage_y_min = -9;
            voltage_x_max = 9;
            voltage_y_max = 9;
            exposure = 100;
            galvo_path = strcat(root, "/hardware/galvo");
            save(strcat(galvo_path, '/galvo_setting.mat'), 'num_galvo_0', 'num_galvo_1', 'voltage_x_min', 'voltage_x_max', 'voltage_y_min', 'voltage_y_max', 'exposure');
            galvo_setting_input(galvo_path);
            app.Lamp.Color = 'g';
        end

        % Button pushed function: JitterButton
        function JitterButtonPushed(app, event)
            root = app.RootEditField.Value;
            jitter_path = strcat(root, "/hardware/jitter");
            input_path = strcat(root, "/input");
            output_path = strcat(root, "/output");
            num_jitter_0 = 4;
            num_jitter_1 = 4;
            voltage_x_min = -2.1;
            voltage_x_max = -1.9;
            voltage_y_min = -0.1;
            voltage_y_max = 0.1;
            exposure = 1000;
            % save setting
            save(strcat(jitter_path, '/jitter_setting.mat'), 'num_jitter_0', 'num_jitter_1', 'voltage_x_min', 'voltage_x_max', 'voltage_y_min', 'voltage_y_max', 'exposure');
            jitter_setting_input(jitter_path, input_path);
            while(1)
                flag = fileread(strcat(input_path, "/running_flag.txt"));
                if flag == '0'
                    break
                end
                i = 0;
                while(i<100)
                    i = i+1;
                end
            end
            load(strcat(output_path, "/data.mat"), 'data');
            save(strcat(jitter_path, "/jitter_output.mat"), 'data');
            jitter_output_parameters(jitter_path);
            j_p = load(strcat(jitter_path, "/jitter_parameters.mat"));
            cla(app.UIAxes);
            plot(app.UIAxes,j_p.hist);
            app.Lamp_2.Color = 'g';
            app.FWHMLabel.Visible = 1;
            app.Label.Visible = 1;
            app.Label.Text = num2str(j_p.FWHM);
            app.loss.Text = num2str(j_p.loss);
        end

        % Button pushed function: RelayWallButton
        function RelayWallButtonPushed(app, event)
            wall_path = app.WallPathEditField.Value;
            [filepath,~,~] = fileparts(pwd);
            root = strcat(filepath,'/resources');
            jitter_path = strcat(root, "/hardware/jitter");
            galvo_path = strcat(root, "/hardware/galvo");
            input_path = strcat(root, "/input");
            output_path = strcat(root, "/output");
            num_wall_0 = 8;
            num_wall_1 = 8;
            distance_threshold_0 = 0.5;
            distance_threshold_1 = 4;
            luminance_threshold_0 = 0;
            luminance_threshold_1 = 999999;
            save(strcat(wall_path, '/wall_setting.mat'), 'num_wall_0', 'num_wall_1', 'distance_threshold_0', 'distance_threshold_1', 'luminance_threshold_0', 'luminance_threshold_1');
            wall_setting_input(jitter_path, galvo_path, wall_path, input_path);
            while(1)
                flag = fileread(strcat(input_path, "/running_flag.txt"));
                if flag == '0'
                    break
                end
                i = 0;
                while(i<100)
                    i = i+1;
                end
            end
            load(strcat(output_path, "/data.mat"), 'data');
            save(strcat(wall_path, "/wall_output.mat"), 'data');
            wall_output_parameters(root, wall_path);
            cla(app.UIAxes);
            w_p = load(strcat(wall_path, "/wall_parameters.mat"));
            app.FWHMLabel.Visible = 0;
            app.Label.Visible = 0;
            app.loss.Text = num2str(w_p.loss);
            long = length(w_p.if_visible);
            hold(app.UIAxes,'on');
            for i = 1:long
                if w_p.if_visible(i)
                    scatter(app.UIAxes,w_p.positions(1,i),w_p.positions(2,i),'green');
                else
                    scatter(app.UIAxes,w_p.positions(1,i),w_p.positions(2,i),'red');
                end
            end
            hold(app.UIAxes,'off');
            app.Lamp_3.Color = 'g';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Logo = uiimage(app.UIFigure);
            app.Logo.ImageSource = 'logo.png';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Background.png';
            app.CalibrationLabel = uilabel(app.UIFigure);
            app.CalibrationLabel.Text = 'Calibration';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Background1.png';
            app.GalvoButton = uibutton(app.UIFigure, 'push');
            app.GalvoButton.ButtonPushedFcn = createCallbackFcn(app, @GalvoButtonPushed, true);
            app.GalvoButton.Text = 'Galvo';
            app.JitterButton = uibutton(app.UIFigure, 'push');
            app.JitterButton.ButtonPushedFcn = createCallbackFcn(app, @JitterButtonPushed, true);
            app.JitterButton.Text = 'Jitter';
            app.RelayWallButton = uibutton(app.UIFigure, 'push');
            app.RelayWallButton.ButtonPushedFcn = createCallbackFcn(app, @RelayWallButtonPushed, true);
            app.RelayWallButton.Text = 'Relay Wall ';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.FinsihButton = uibutton(app.UIFigure, 'push');
            app.FinsihButton.ButtonPushedFcn = createCallbackFcn(app, @FinsihButtonPushed, true);
            app.FinsihButton.Text = 'Finsih';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.ChangeButton = uibutton(app.UIFigure, 'push');
            app.ChangeButton.Visible = 'off';
            app.ChangeButton.Text = 'Change';
            app.ChangeButton_2 = uibutton(app.UIFigure, 'push');
            app.ChangeButton_2.Visible = 'off';
            app.ChangeButton_2.Text = 'Change';
            app.ChangeButton_3 = uibutton(app.UIFigure, 'push');
            app.ChangeButton_3.Visible = 'off';
            app.ChangeButton_3.Text = 'Change';
            app.WallPathEditFieldLabel = uilabel(app.UIFigure);
            app.WallPathEditFieldLabel.Text = 'Wall Path';
            app.WallPathEditField = uieditfield(app.UIFigure, 'text');
            app.LossLabel = uilabel(app.UIFigure);
            app.LossLabel.Text = 'Loss: ';
            app.loss = uilabel(app.UIFigure);
            app.loss.Text = '0';
            app.FWHMLabel = uilabel(app.UIFigure);
            app.FWHMLabel.Visible = 'off';
            app.FWHMLabel.Text = 'FWHM: ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '0';
            app.RootEditFieldLabel = uilabel(app.UIFigure);
            app.RootEditFieldLabel.Text = 'Root';
            app.RootEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalibrationPageUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end