classdef EnhancePageUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Logo                           matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        EnhanceLabel                   matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        ReturnButton                   matlab.ui.control.Button
        FinsihButton                   matlab.ui.control.Button
        NLoSPathEditFieldLabel         matlab.ui.control.Label
        NLoSPathEditField              matlab.ui.control.EditField
        LoSPathEditFieldLabel          matlab.ui.control.Label
        LoSPathEditField               matlab.ui.control.EditField
        IfDenoiseCheckBox              matlab.ui.control.CheckBox
        IfFittingTranditionalCheckBox  matlab.ui.control.CheckBox
        EnhancePathEditFieldLabel      matlab.ui.control.Label
        EnhancePathEditField           matlab.ui.control.EditField
        IfDegammaCheckBox              matlab.ui.control.CheckBox
        IfOneShotCheckBox              matlab.ui.control.CheckBox
        OneShotPathLabel               matlab.ui.control.Label
        OneShotPathEditField           matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        page % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            app.page.pres = app.page.Page_enhance;
            app.page.next = app.page.Page_home;
            app.page.Switch_Page();
        end

        % Button pushed function: FinsihButton
        function FinsihButtonPushed(app, event)
            root = "C:/Users/xxy/Desktop/Inphormatool/resources";
            if_denoise = app.IfDenoiseCheckBox.Value;
            if_traditional = app.IfFittingTranditionalCheckBox.Value;
            if_deGamma = app.IfDegammaCheckBox.Value;
            if_oneshot = app.IfOneShotCheckBox.Value;
            los_path = app.LoSPathEditField.Value;
            nlos_path = app.NLoSPathEditField.Value;
            enhance_path = app.EnhancePathEditField.Value;
            threshold_0 = 0;
            load(strcat(root, "/global_parameters.mat" ),"num_bin");
            threshold_1 = num_bin;
            SNR = 20;
            jitter_path = strcat(root, "/hardware/jitter");
            %save(strcat(enhance_path,"/enhance_setting.mat"),"threshold_1","threshold_0","nlos_path","los_path","if_traditional","if_deGamma","if_denoise","SNR");
            if if_oneshot
                oneshot_path = app.OneShotPathEditField.Value;
                save(strcat(enhance_path,"/enhance_setting.mat"),"threshold_1","threshold_0","nlos_path","los_path","if_traditional","if_deGamma","if_denoise","SNR","if_oneshot","oneshot_path");
                enhance_setting_data_oneshot(root, enhance_path, jitter_path);
            else
                save(strcat(enhance_path,"/enhance_setting.mat"),"threshold_1","threshold_0","nlos_path","los_path","if_traditional","if_deGamma","if_denoise","SNR");
                enhance_setting_data(root, enhance_path, jitter_path);
            end
        end

        % Value changed function: IfOneShotCheckBox
        function IfOneShotCheckBoxValueChanged(app, event)
            value = app.IfOneShotCheckBox.Value;
            if (value)
                app.OneShotPathLabel.Visible = 1;
                app.OneShotPathEditField.Visible = 1;
            else
                app.OneShotPathLabel.Visible = 0;
                app.OneShotPathEditField.Visible = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Logo = uiimage(app.UIFigure);
            app.Logo.ImageSource = 'logo.png';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Background.png';
            app.EnhanceLabel = uilabel(app.UIFigure);
            app.EnhanceLabel.Text = 'Enhance';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Background4.png';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.FinsihButton = uibutton(app.UIFigure, 'push');
            app.FinsihButton.ButtonPushedFcn = createCallbackFcn(app, @FinsihButtonPushed, true);
            app.FinsihButton.Text = 'Finsih';
            app.NLoSPathEditFieldLabel = uilabel(app.UIFigure);
            app.NLoSPathEditFieldLabel.Text = 'NLoS Path';
            app.NLoSPathEditField = uieditfield(app.UIFigure, 'text');
            app.LoSPathEditFieldLabel = uilabel(app.UIFigure);
            app.LoSPathEditFieldLabel.Text = 'LoS Path';
            app.LoSPathEditField = uieditfield(app.UIFigure, 'text');
            app.IfDenoiseCheckBox = uicheckbox(app.UIFigure);
            app.IfDenoiseCheckBox.Text = 'If Denoise';
            app.IfFittingTranditionalCheckBox = uicheckbox(app.UIFigure);
            app.IfFittingTranditionalCheckBox.Text = 'If Fitting Tranditional';
            app.EnhancePathEditFieldLabel = uilabel(app.UIFigure);
            app.EnhancePathEditFieldLabel.Text = 'Enhance Path';
            app.EnhancePathEditField = uieditfield(app.UIFigure, 'text');
            app.IfDegammaCheckBox = uicheckbox(app.UIFigure);
            app.IfDegammaCheckBox.Text = 'If Degamma';
            app.IfOneShotCheckBox = uicheckbox(app.UIFigure);
            app.IfOneShotCheckBox.ValueChangedFcn = createCallbackFcn(app, @IfOneShotCheckBoxValueChanged, true);
            app.IfOneShotCheckBox.Text = 'If One Shot';
            app.OneShotPathLabel = uilabel(app.UIFigure);
            app.OneShotPathLabel.Visible = 'off';
            app.OneShotPathLabel.Text = 'One Shot Path';
            app.OneShotPathEditField = uieditfield(app.UIFigure, 'text');
            app.OneShotPathEditField.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EnhancePageUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end