classdef GlobalParamsUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Logo                          matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        GlobalParametersLabel         matlab.ui.control.Label
        speedlightEditFieldLabel      matlab.ui.control.Label
        SpeedLightEditField           matlab.ui.control.NumericEditField
        speedlightEditFieldLabel_2    matlab.ui.control.Label
        RepititionFrequencyEditField  matlab.ui.control.NumericEditField
        speedlightEditFieldLabel_3    matlab.ui.control.Label
        TimeBinEditField              matlab.ui.control.NumericEditField
        speedlightEditFieldLabel_4    matlab.ui.control.Label
        NumBinEditField               matlab.ui.control.NumericEditField
        speedlightEditFieldLabel_5    matlab.ui.control.Label
        GalvanometerPeakEditField     matlab.ui.control.NumericEditField
        speedlightEditFieldLabel_6    matlab.ui.control.Label
        DealyOffsetEditField          matlab.ui.control.NumericEditField
        RootEditFieldLabel            matlab.ui.control.Label
        RootEditField                 matlab.ui.control.EditField
        ReturnButton                  matlab.ui.control.Button
        FinishButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        page % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            root = app.RootEditField.Value;
            speed_light = app.SpeedLightEditField.Value;
            r_freq = app.RepititionFrequencyEditField.Value;
            time_bin = app.TimeBinEditField.Value;
            app.NumBinEditField.Value = ceil(1/r_freq/time_bin);
            num_bin = app.NumBinEditField.Value;
            galvanometer_peak = app.GalvanometerPeakEditField.Value;
            delay_offset = app.DealyOffsetEditField.Value;
            save(strcat(root, '/global_parameters.mat'), 'speed_light', 'time_bin', 'r_freq', 'num_bin', 'galvanometer_peak', 'delay_offset');
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            app.page.pres = app.page.Page_params;
            app.page.next = app.page.Page_home;
            app.page.Switch_Page();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Logo = uiimage(app.UIFigure);
            app.Logo.ImageSource = 'logo.png';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Background.png';
            app.GlobalParametersLabel = uilabel(app.UIFigure);
            app.GlobalParametersLabel.Text = 'Global Parameters';
            app.speedlightEditFieldLabel = uilabel(app.UIFigure);
            app.speedlightEditFieldLabel.Text = 'speed light';
            app.SpeedLightEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedLightEditField.Value = 300000000;
            app.speedlightEditFieldLabel_2 = uilabel(app.UIFigure);
            app.speedlightEditFieldLabel_2.Text = 'repitition frequency';
            app.RepititionFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.speedlightEditFieldLabel_3 = uilabel(app.UIFigure);
            app.speedlightEditFieldLabel_3.Text = 'time bin';
            app.TimeBinEditField = uieditfield(app.UIFigure, 'numeric');
            app.speedlightEditFieldLabel_4 = uilabel(app.UIFigure);
            app.speedlightEditFieldLabel_4.Text = 'num bin';
            app.NumBinEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumBinEditField.Editable = 'off';
            app.speedlightEditFieldLabel_5 = uilabel(app.UIFigure);
            app.speedlightEditFieldLabel_5.Text = 'galvanometer peak';
            app.GalvanometerPeakEditField = uieditfield(app.UIFigure, 'numeric');
            app.speedlightEditFieldLabel_6 = uilabel(app.UIFigure);
            app.speedlightEditFieldLabel_6.Text = 'delay offset';
            app.DealyOffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.RootEditFieldLabel = uilabel(app.UIFigure);
            app.RootEditFieldLabel.Text = 'Root';
            app.RootEditField = uieditfield(app.UIFigure, 'text');
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GlobalParamsUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end