classdef MainPageUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EnhancementButton               matlab.ui.control.Button
        AutoButton                      matlab.ui.control.Button
        MannualButton_2                 matlab.ui.control.Button
        CalibrationButton               matlab.ui.control.Button
        Logo                            matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        NoteLabel                       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        AutoCapturePresstomeasurewithalistofpointsLabel  matlab.ui.control.Label
        ReconstructionPresstoshowdifferentmethodofreconstructionLabel  matlab.ui.control.Label
        GalvanometerCalibrationLampLabel  matlab.ui.control.Label
        GalvanometerCalibrationLamp     matlab.ui.control.Lamp
        TimeJitterCalibrationLampLabel  matlab.ui.control.Label
        TimeJitterCalibrationLamp       matlab.ui.control.Lamp
        RelyWallCalibrationLampLabel    matlab.ui.control.Label
        RelyWallCalibrationLamp         matlab.ui.control.Lamp
        ReconstructionButton_2          matlab.ui.control.Button
        GlobalPatametersButton          matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        RunningLampLabel                matlab.ui.control.Label
        RunningLamp                     matlab.ui.control.Lamp
        Image3                          matlab.ui.control.Image
        methodDropDownLabel             matlab.ui.control.Label
        methodDropDown                  matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        page
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalibrationButton
        function CalibrationButtonPushed(app, event)
            app.page.pres = app.page.Page_home;
            app.page.next = app.page.Page_cali;
            app.page.Switch_Page();
            %app.RootEditField.Value
        end

        % Button pushed function: MannualButton_2
        function MannualButton_2Pushed(app, event)
            app.page.pres = app.page.Page_home;
            app.page.next = app.page.Page_mannual;
            app.page.Switch_Page();
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            app.page.pres = app.page.Page_home;
            app.page.next = app.page.Page_auto;
            app.page.Switch_Page();
        end

        % Button pushed function: EnhancementButton
        function EnhancementButtonPushed(app, event)
            app.page.pres = app.page.Page_home;
            app.page.next = app.page.Page_enhance;
            app.page.Switch_Page();
        end

        % Button pushed function: ReconstructionButton_2
        function ReconstructionButton_2Pushed(app, event)
            app.page.pres = app.page.Page_home;
            app.page.next = app.page.Page_recon;
            app.page.Switch_Page();
        end

        % Button pushed function: GlobalPatametersButton
        function GlobalPatametersButtonPushed(app, event)
            app.page.pres = app.page.Page_home;
            app.page.next = app.page.Page_params;
            app.page.Switch_Page();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EnhancementButton = uibutton(app.UIFigure, 'push');
            app.EnhancementButton.ButtonPushedFcn = createCallbackFcn(app, @EnhancementButtonPushed, true);
            app.EnhancementButton.Text = 'Enhancement';
            app.AutoButton = uibutton(app.UIFigure, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.MannualButton_2 = uibutton(app.UIFigure, 'push');
            app.MannualButton_2.ButtonPushedFcn = createCallbackFcn(app, @MannualButton_2Pushed, true);
            app.MannualButton_2.Enable = 'off';
            app.MannualButton_2.Text = 'Mannual';
            app.CalibrationButton = uibutton(app.UIFigure, 'push');
            app.CalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.CalibrationButton.Text = 'Calibration';
            app.Logo = uiimage(app.UIFigure);
            app.Logo.ImageSource = 'logo.png';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Background.png';
            app.NoteLabel = uilabel(app.UIFigure);
            app.NoteLabel.Text = '1. Calibration: Press to calibration the system.';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '2. Mannul Capture: Press to measure with mannul selected points.';
            app.AutoCapturePresstomeasurewithalistofpointsLabel = uilabel(app.UIFigure);
            app.AutoCapturePresstomeasurewithalistofpointsLabel.Text = '3. Auto Capture: Press to measure with a list of points.';
            app.ReconstructionPresstoshowdifferentmethodofreconstructionLabel = uilabel(app.UIFigure);
            app.ReconstructionPresstoshowdifferentmethodofreconstructionLabel.Text = '4. Reconstruction: Press to show different method of reconstruction.';
            app.GalvanometerCalibrationLampLabel = uilabel(app.UIFigure);
            app.GalvanometerCalibrationLampLabel.Enable = 'off';
            app.GalvanometerCalibrationLampLabel.Text = 'Galvanometer Calibration';
            app.GalvanometerCalibrationLamp = uilamp(app.UIFigure);
            app.GalvanometerCalibrationLamp.Enable = 'off';
            app.TimeJitterCalibrationLampLabel = uilabel(app.UIFigure);
            app.TimeJitterCalibrationLampLabel.Enable = 'off';
            app.TimeJitterCalibrationLampLabel.Text = 'Time Jitter Calibration';
            app.TimeJitterCalibrationLamp = uilamp(app.UIFigure);
            app.TimeJitterCalibrationLamp.Enable = 'off';
            app.RelyWallCalibrationLampLabel = uilabel(app.UIFigure);
            app.RelyWallCalibrationLampLabel.Enable = 'off';
            app.RelyWallCalibrationLampLabel.Text = 'Rely Wall Calibration';
            app.RelyWallCalibrationLamp = uilamp(app.UIFigure);
            app.RelyWallCalibrationLamp.Enable = 'off';
            app.ReconstructionButton_2 = uibutton(app.UIFigure, 'push');
            app.ReconstructionButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReconstructionButton_2Pushed, true);
            app.ReconstructionButton_2.Text = 'Reconstruction';
            app.GlobalPatametersButton = uibutton(app.UIFigure, 'push');
            app.GlobalPatametersButton.ButtonPushedFcn = createCallbackFcn(app, @GlobalPatametersButtonPushed, true);
            app.GlobalPatametersButton.Text = 'Global Patameters';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Enable = 'off';
            app.Image2.ImageSource = 'auto.png';
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Enable = 'off';
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.UIFigure);
            app.RunningLamp.Enable = 'off';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.Enable = 'off';
            app.Image3.ImageSource = 'edit.png';
            app.methodDropDownLabel = uilabel(app.UIFigure);
            app.methodDropDownLabel.Enable = 'off';
            app.methodDropDownLabel.Text = 'method';
            app.methodDropDown = uidropdown(app.UIFigure);
            app.methodDropDown.Items = {'LCT', 'Option 2', 'Option 3', 'Option 4'};
            app.methodDropDown.Enable = 'off';
            app.methodDropDown.Value = 'LCT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainPageUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end