classdef MannualPageUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Logo                    matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        MannualLabel            matlab.ui.control.Label
        Image2                  matlab.ui.control.Image
        ReturnButton            matlab.ui.control.Button
        FinsihButton            matlab.ui.control.Button
        DelayEditFieldLabel     matlab.ui.control.Label
        DelayEditField          matlab.ui.control.EditField
        ExposureEditFieldLabel  matlab.ui.control.Label
        ExposureEditField       matlab.ui.control.EditField
        XVoltageEditFieldLabel  matlab.ui.control.Label
        XVoltageEditField       matlab.ui.control.EditField
        YVoltageEditFieldLabel  matlab.ui.control.Label
        YVoltageEditField       matlab.ui.control.EditField
        PathEditFieldLabel      matlab.ui.control.Label
        PathEditField           matlab.ui.control.EditField
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        page % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            app.page.pres = app.page.Page_mannual;
            app.page.next = app.page.Page_home;
            app.page.Switch_Page();
        end

        % Button pushed function: FinsihButton
        function FinsihButtonPushed(app, event)
            app.page.pres = app.page.Page_mannual;
            app.page.next = app.page.Page_home;
            app.page.Switch_Page();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Logo = uiimage(app.UIFigure);
            app.Logo.ImageSource = 'logo.png';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Background.png';
            app.MannualLabel = uilabel(app.UIFigure);
            app.MannualLabel.Text = 'Mannual';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Background3.png';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.FinsihButton = uibutton(app.UIFigure, 'push');
            app.FinsihButton.ButtonPushedFcn = createCallbackFcn(app, @FinsihButtonPushed, true);
            app.FinsihButton.Text = 'Finsih';
            app.DelayEditFieldLabel = uilabel(app.UIFigure);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.UIFigure, 'text');
            app.ExposureEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureEditFieldLabel.Text = 'Exposure';
            app.ExposureEditField = uieditfield(app.UIFigure, 'text');
            app.XVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.XVoltageEditFieldLabel.Text = 'X Voltage';
            app.XVoltageEditField = uieditfield(app.UIFigure, 'text');
            app.YVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.YVoltageEditFieldLabel.Text = 'Y Voltage';
            app.YVoltageEditField = uieditfield(app.UIFigure, 'text');
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MannualPageUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end